classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PausaEditField                matlab.ui.control.NumericEditField
        PausaEditFieldLabel           matlab.ui.control.Label
        CerrarButton                  matlab.ui.control.Button
        RegionPropsPanel              matlab.ui.container.Panel
        NegativaCheckBox              matlab.ui.control.CheckBox
        BWUIAxes                      matlab.ui.control.UIAxes
        QuadTreePanel                 matlab.ui.container.Panel
        MultiBoxesCheckBox            matlab.ui.control.CheckBox
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        AlgoritmoButtonGroup          matlab.ui.container.ButtonGroup
        DTButton                      matlab.ui.control.ToggleButton
        SVMButton                     matlab.ui.control.ToggleButton
        KNNButton                     matlab.ui.control.ToggleButton
        ANNButton                     matlab.ui.control.ToggleButton
        SeparaciondeareasButtonGroup  matlab.ui.container.ButtonGroup
        AmbosButton                   matlab.ui.control.ToggleButton
        QuadtreeButton                matlab.ui.control.ToggleButton
        RegionPropsButton             matlab.ui.control.ToggleButton
        NingunaButton                 matlab.ui.control.ToggleButton
        ColoresListBox                matlab.ui.control.ListBox
        ColoresListBoxLabel           matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Repeat = true; % Description
        Pause = 1; 

        Algo = 'ann'; % Description

        ResizeQT = 512; 
        ThresholdQT = 0.5; 
        DimsQT = [512 256 128 64]; 


        NegativeRP = true; 
        MultiBoxes = false; 
    end
    
    
    methods (Access = private)

        function img = capture(app)
            cam = webcam('Integrated');
            img = snapshot(cam); 
        end

        function execute(app,execute_algo)
            clc; 
            app.Repeat = true;
            app.UIAxes.Visible = "on"; 
            while app.Repeat
%                 try
                    tic
                    img = capture(app); 
                    predictions = execute_algo(app, img); 
                    app.ColoresListBox.Items = unique(predictions(:,2));
                    fprintf("%s ", app.Algo); 
                    toc
                    pause(app.Pause);
%                 catch 
%                     
% 
%                 end
                 
            end
        end

        function predictions = execute_quadtree(app, img)
            predictions = app_predict_quadtree(img,app.ThresholdQT, app.DimsQT,app.Algo, app.UIAxes, app.MultiBoxes);
        end

        function predictions = execute_regionprops(app, img)
            predictions = app_predict_regionprops(img, app.Algo , app.NegativeRP , app.UIAxes, app.BWUIAxes); 
        end

        function predictions = execute_both(app, img)
            predictions = app_predict_regionprops_quadtree(img, app.Algo , app.ThresholdQT, app.NegativeRP, app.MultiBoxes, app.UIAxes, app.BWUIAxes);
        end

        function predictions = execute_preview(app, img)
            imshow(img, 'Parent', app.UIAxes); 
            pause(3); 
            predictions = ['Ninguna']; 
        end

        function handler = get_handler(app, algo)
            handler = false; 
            app.UIAxes.Title.String = algo; 
            switch algo
                case 'RegionProps'
                    handler = @execute_regionprops; 
                case 'Quadtree'
                    handler = @execute_quadtree; 
                case 'Ambos'
                    handler = @execute_both; 
                otherwise
                    handler = @execute_preview; 
                    fprintf("Paused"); 
                    
            end
        end

        function select_algorithm(app, algo)
            switch algo
                case 'ANN'
                    app.Algo = 'ann'; 
                case 'KNN'
                    app.Algo = 'knn'; 
                case 'SVM'
                    app.Algo = 'svm'; 
                case 'DT'
                    app.Algo = 'dt'; 
                otherwise 
                    app.Algo = 'ann'; 
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: SeparaciondeareasButtonGroup
        function SeparaciondeareasButtonGroupSelectionChanged(app, event)
            selectedButton = app.SeparaciondeareasButtonGroup.SelectedObject;
            algo = selectedButton.Text; 

            app.Repeat = false; 
            
            handler = get_handler(app, algo); 

            type_of = class(handler); 

            if contains(type_of, 'function_handle')
                execute(app, handler);  
            end
            
        end

        % Selection changed function: AlgoritmoButtonGroup
        function AlgoritmoButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgoritmoButtonGroup.SelectedObject;
            app.UIAxes.Visible = "off"; 
            select_algorithm(app, selectedButton.Text); 
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.ThresholdQT = value; 
        end

        % Value changed function: NegativaCheckBox
        function NegativaCheckBoxValueChanged(app, event)
            value = app.NegativaCheckBox.Value;
            if value 
                app.NegativeRP = true; 
            else
                app.NegativeRP = false; 
            end
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete; 
        end

        % Value changed function: PausaEditField
        function PausaEditFieldValueChanged(app, event)
            value = app.PausaEditField.Value;
            app.Pause = value; 
        end

        % Value changed function: MultiBoxesCheckBox
        function MultiBoxesCheckBoxValueChanged(app, event)
            value = app.MultiBoxesCheckBox.Value;
            app.MultiBoxes = value; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ColoresListBoxLabel = uilabel(app.UIFigure);
            app.ColoresListBoxLabel.Text = 'Colores';
            app.ColoresListBox = uilistbox(app.UIFigure);
            app.SeparaciondeareasButtonGroup = uibuttongroup(app.UIFigure);
            app.SeparaciondeareasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeparaciondeareasButtonGroupSelectionChanged, true);
            app.SeparaciondeareasButtonGroup.Title = 'Separacion de areas';
            app.NingunaButton = uitogglebutton(app.SeparaciondeareasButtonGroup);
            app.NingunaButton.Text = 'Ninguna';
            app.NingunaButton.Value = true;
            app.RegionPropsButton = uitogglebutton(app.SeparaciondeareasButtonGroup);
            app.RegionPropsButton.Text = 'RegionProps';
            app.QuadtreeButton = uitogglebutton(app.SeparaciondeareasButtonGroup);
            app.QuadtreeButton.Text = 'Quadtree';
            app.AmbosButton = uitogglebutton(app.SeparaciondeareasButtonGroup);
            app.AmbosButton.Text = 'Ambos';
            app.AlgoritmoButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgoritmoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgoritmoButtonGroupSelectionChanged, true);
            app.AlgoritmoButtonGroup.Title = 'Algoritmo';
            app.ANNButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.ANNButton.Text = 'ANN';
            app.ANNButton.Value = true;
            app.KNNButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.SVMButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.DTButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.DTButton.Text = 'DT';
            app.QuadTreePanel = uipanel(app.UIFigure);
            app.QuadTreePanel.Title = 'QuadTree';
            app.ThresholdEditFieldLabel = uilabel(app.QuadTreePanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.QuadTreePanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.8;
            app.MultiBoxesCheckBox = uicheckbox(app.QuadTreePanel);
            app.MultiBoxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiBoxesCheckBoxValueChanged, true);
            app.MultiBoxesCheckBox.Text = 'MultiBoxes';
            app.RegionPropsPanel = uipanel(app.UIFigure);
            app.RegionPropsPanel.Title = 'RegionProps';
            app.BWUIAxes = uiaxes(app.RegionPropsPanel);
            title(app.BWUIAxes, 'Black/White')
            zlabel(app.BWUIAxes, 'Z')
            app.NegativaCheckBox = uicheckbox(app.RegionPropsPanel);
            app.NegativaCheckBox.ValueChangedFcn = createCallbackFcn(app, @NegativaCheckBoxValueChanged, true);
            app.NegativaCheckBox.Text = 'Negativa';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.PausaEditFieldLabel = uilabel(app.UIFigure);
            app.PausaEditFieldLabel.Text = 'Pausa';
            app.PausaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PausaEditField.ValueChangedFcn = createCallbackFcn(app, @PausaEditFieldValueChanged, true);
            app.PausaEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end