classdef main_previous < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RedesneuronalesPanel          matlab.ui.container.Panel
        resultado                     matlab.ui.control.Label
        PlacadetectadaEditField       matlab.ui.control.EditField
        PlacadetectadaEditFieldLabel  matlab.ui.control.Label
        AccionesPanel                 matlab.ui.container.Panel
        BuscarButton                  matlab.ui.control.Button
        CapturarimagenButton          matlab.ui.control.Button
        EncendercmaraButton           matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        DatosdelaBsquedaPanel         matlab.ui.container.Panel
        ApellidoEditField             matlab.ui.control.EditField
        ApellidoEditFieldLabel        matlab.ui.control.Label
        NombreEditField               matlab.ui.control.EditField
        NombreEditFieldLabel          matlab.ui.control.Label
        cedulaUsuario                 matlab.ui.control.EditField
        CdulaEditFieldLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = procesarImagen(app)
            global img;
            global res;
            
            img = imresize(img,0.50); % reducir tamanio en un 50%
            imshow(img)
            pause(0.50);
            
            img = rgb2gray(img); %escala grises
            imshow(img)
            pause(0.50);
            
            img = medfilt2(img); %limpiar ruido
            imshow(img);
            pause(0.20);
            
            img = img * 1.50; %aumentar contraste
            imshow(img);
            pause(0.20);
            
            img = im2bw(img); %binarizar imagen
            imshow(img)
            pause(0.50);
            
            img = imcomplement(img);
            imshow(img)
            pause(0.50);
            
            img = bwareaopen(img,700); %eliminar ruido de regiones pequeñas
            imshow(img)
            pause(0.20);
            
            se = strel('disk', 3); %conectar pixeles de un mismo objeto
            img = imclose(img, se);
            imshow(img)
            pause(0.20);
            
            %             imgRV=edge(img,'Canny', 'vertical');
            %             imgRH=edge(img,'log', 'horizontal');
            %             img = imgRV+imgRH;
            % %
            
            %
            %
            
            img = mat2gray(img);
            
            [B, L] = bwboundaries(img,'noholes'); % Rastrea los bordes exteriores de cada objeto
            %imshow(label2rgb(L, @jet, [.5 .5 .5]));
            hold on
            
            for k = 1: length(B) boundary = B{k};
                plot(boundary(:, 2), boundary(:,1), 'w', 'LineWidth', 2)
            end
            
            propiedades = regionprops(L, 'Area','Centroid', 'BoundingBox','Image');
            treshold = 0.94;
            
            load ('C:\Users\PC\Documents\UTA\SEPTIMO\IA\proyecto\trainDT.mat');
            
            etiquetas= [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 12 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36];
            
            respuestas = ['0', '1', '2', '3', '4', '5', '6' ,'7' ,'8', '9', 'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P' 'Q' 'R' 'S' 'T' 'U' 'V' 'W' 'X' 'Y' 'Z'];
            res = []
            
            for i = 1 : length(B)
                figure(i + 1)
                imgCrop = imcrop(img, propiedades(i).BoundingBox);
                
                size(imgCrop)
                
                imshow(imgCrop)
                
                %transformar a 28 x 28
                imgCrop = imresize(imgCrop, [28 28]);
                size(imgCrop)
                
                imshow(imgCrop)
                
                entrada = reshape(imgCrop, [1 784]);
                
                etiquetaTest = trainedModel.predictFcn(entrada);
                
                [fil, col]=find(etiquetas==etiquetaTest)
                resultado=respuestas(1,col)
                res=[res,resultado]
                %imshow(reshape(dataset.train.imagenes(res,:), [28 28]))
                app.PlacadetectadaEditField.Value = res
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncendercmaraButton
        function EncendercmaraButtonPushed(app, event)
            %activacion de camara
            global video
            video = videoinput('winvideo',1);
            start(video);
            preview(video);
        end

        % Button pushed function: CapturarimagenButton
        function CapturarimagenButtonPushed(app, event)
            %capturo la imagen
            global video
            global img
            img = imread('./placa3.jpg');
            %img = getsnapshot(video);
            app.Image.ImageSource = img;
            %stop(video);
            %closepreview(video);
            
            procesarImagen(app)
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            
            %conexion a BDD
            global res;
            conn = database('matlab','root','');                
            query="SELECT * FROM usuarios WHERE usuarios.placaVehiculo = ";
            comilla="'";
            placa = res;
            placa=strcat(comilla,placa);
            placa=strcat(placa,comilla);
            query=strcat(query,placa);
            datos=select(conn,query);

            if isempty(datos)
                fig = uifigure;                
                app.resultado.Text = 'No existe'
            end
         %   query="SELECT cedulaUsuario, nombreUsuario, apellidoUsuario FROM usuarios WHERE usuarios.placaVehiculo = ";

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedesneuronalesPanel = uipanel(app.UIFigure);
            app.RedesneuronalesPanel.Title = 'Redes neuronales';
            app.DatosdelaBsquedaPanel = uipanel(app.RedesneuronalesPanel);
            app.DatosdelaBsquedaPanel.Title = 'Datos de la Búsqueda';
            app.CdulaEditFieldLabel = uilabel(app.DatosdelaBsquedaPanel);
            app.CdulaEditFieldLabel.Text = 'Cédula';
            app.cedulaUsuario = uieditfield(app.DatosdelaBsquedaPanel, 'text');
            app.cedulaUsuario.Tag = 'txtCedula';
            app.cedulaUsuario.Editable = 'off';
            app.NombreEditFieldLabel = uilabel(app.DatosdelaBsquedaPanel);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.DatosdelaBsquedaPanel, 'text');
            app.NombreEditField.Tag = 'txtNombre';
            app.NombreEditField.Editable = 'off';
            app.ApellidoEditFieldLabel = uilabel(app.DatosdelaBsquedaPanel);
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.DatosdelaBsquedaPanel, 'text');
            app.ApellidoEditField.Tag = 'txtApellido';
            app.ApellidoEditField.Editable = 'off';
            app.Image = uiimage(app.RedesneuronalesPanel);
            app.AccionesPanel = uipanel(app.RedesneuronalesPanel);
            app.AccionesPanel.Title = 'Acciones';
            app.EncendercmaraButton = uibutton(app.AccionesPanel, 'push');
            app.EncendercmaraButton.ButtonPushedFcn = createCallbackFcn(app, @EncendercmaraButtonPushed, true);
            app.EncendercmaraButton.Tag = 'btnEncenderCamara';
            app.EncendercmaraButton.Text = 'Encender cámara';
            app.CapturarimagenButton = uibutton(app.AccionesPanel, 'push');
            app.CapturarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarimagenButtonPushed, true);
            app.CapturarimagenButton.Tag = 'btnCapturarImagen';
            app.CapturarimagenButton.Text = 'Capturar imagen';
            app.BuscarButton = uibutton(app.AccionesPanel, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Tag = 'btnBuscarConductor';
            app.BuscarButton.Text = {'Buscar'; ''};
            app.PlacadetectadaEditFieldLabel = uilabel(app.RedesneuronalesPanel);
            app.PlacadetectadaEditFieldLabel.Text = 'Placa detectada';
            app.PlacadetectadaEditField = uieditfield(app.RedesneuronalesPanel, 'text');
            app.PlacadetectadaEditField.Editable = 'off';
            app.PlacadetectadaEditField.Enable = 'off';
            app.resultado = uilabel(app.RedesneuronalesPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_previous

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end